classdef ShimmerPPG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        LiveTab                matlab.ui.container.Tab
        RecordingLampLabel     matlab.ui.control.Label
        RecordingLamp          matlab.ui.control.Lamp
        GoButton               matlab.ui.control.Button
        PPGNumEditFieldLabel   matlab.ui.control.Label
        PPGNumEditField        matlab.ui.control.NumericEditField
        TimeEditFieldLabel     matlab.ui.control.Label
        TimeEditField          matlab.ui.control.NumericEditField
        PathEditFieldLabel     matlab.ui.control.Label
        PathEditField          matlab.ui.control.EditField
        PlotCheckBox           matlab.ui.control.CheckBox
        SelectButton           matlab.ui.control.Button
        COMPortEditFieldLabel  matlab.ui.control.Label
        COMPortEditField       matlab.ui.control.EditField
        StopButton             matlab.ui.control.Button
        FileEditFieldLabel     matlab.ui.control.Label
        FileEditField          matlab.ui.control.EditField
        ContinuousCheckBox     matlab.ui.control.CheckBox
        WriteToFileCheckBox    matlab.ui.control.CheckBox
        SensorsPanel           matlab.ui.container.Panel
        PPGCheckBox            matlab.ui.control.CheckBox
        AccelerometerCheckBox  matlab.ui.control.CheckBox
        GSRCheckBox            matlab.ui.control.CheckBox
        LoadTab                matlab.ui.container.Tab
        FileEditField_2Label   matlab.ui.control.Label
        FileEditField_2        matlab.ui.control.EditField
        SelectButton_2         matlab.ui.control.Button
        ProcessButton          matlab.ui.control.Button
        CenterPanel            matlab.ui.container.Panel
        PlotsTabGroup          matlab.ui.container.TabGroup
        HeartRateTab           matlab.ui.container.Tab
        HRAxes                 matlab.ui.control.UIAxes
        PPGAxes                matlab.ui.control.UIAxes
        GSRTab                 matlab.ui.container.Tab
        AccelerationTab        matlab.ui.container.Tab
        RightPanel             matlab.ui.container.Panel
        TabGroup2              matlab.ui.container.TabGroup
        ReviewTab              matlab.ui.container.Tab
        Tab2_2                 matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Path % Description
        StopState % Description
    end
    
    properties (Access = private)
        StartTime % Description
        isRunning % Description
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thisPath = string(pwd);
            app.Path = thisPath;
            app.PathEditField.Value = app.Path;
            toolPath = thisPath + "/ShimmerBiophysicalProcessingLibrary_Rev_0_10.jar";
            javaaddpath(toolPath);
            addpath('./Resources/');
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            %% definitions
            comPort = app.COMPortEditField.Value;
            PPGChannelNum = app.PPGNumEditField.Value;
            captureDuration = app.TimeEditField.Value;
            if app.ContinuousCheckBox.Value == true
                captureDuration = inf;
            end
            app.isRunning = true;
            isRecorded = app.WriteToFileCheckBox.Value;
            fileName = "ShimmerRecording_" + datestr(now, 'yyyy_mm_dd-HH_MM') + ".dat";
            app.FileEditField.Value = fileName;
            dataFile = app.Path + "\data\" + fileName;
            app.StopState = false;
            shimmer = ShimmerHandleClass(comPort);          % Define shimmer as a ShimmerHandle Class instance with comPort1
            SensorMacros = SetEnabledSensorsMacrosClass;    % Macros class for sensor selection
            fs = 204.8;                                     % sample rate in [Hz]
            firsttime = true;
            app.RecordingLamp.Color = [1.00,0.00,0.00];
            % Note: these constants are only relevant to this examplescript and are not used
            % by the ShimmerHandle Class
            NO_SAMPLES_IN_PLOT = 2500;                      % Number of samples that will be displayed in the plot at any one time
            DELAY_PERIOD = 0.2;                             % A delay period of time in seconds between data read operations
            numSamples = 0;
            
            %% filter settings
            fclp = 5;                                       % corner frequency lowpassfilter [Hz];
            nPoles = 2;                                     % number of poles (HPF, LPF)
            pbRipple = 0.5;                                 % pass band ripple (%)
            lpfPPG = FilterClass(FilterClass.LPF,fs,...
                fclp,nPoles,pbRipple);                      % lowpass filters for PPG channel
            
            %% PPG2HR settings
            numberOfBeatsToAve = 10;                        % the number of consecutive heart beats that are averaged to calculate the heart rate. Instantaneous heart rate is calculated after each detected pulse. So the last X instantaneous heart rates are averaged to give the output (where X is numberOfBeatsToAve)) must be >= 1.
            useLastEstimate = 0;                            % true for repeating last valid estimate when invalid data is detected.
            PPG2HR = com.shimmerresearch.biophysicalprocessing.PPGtoHRAlgorithm(...
                fs,numberOfBeatsToAve,useLastEstimate);     % create PPG to Heart Rate object: Sampling Rate = 204.8Hz, Number of Beats to Average = 1 (minimum), Repeat last valid estimate when invalid data is detected.

            %% Processing Loop
            if (shimmer.connect)                                                       % TRUE if the shimmer connects
                
                % define settings for shimmer
                shimmer.setsamplingrate(fs);                                           % set the shimmer sampling rate
                shimmer.setinternalboard('9DOF');                                      % Set the shimmer internal daughter board to '9DOF'
                PPGChannel = ['INT A' num2str(PPGChannelNum)];
                shimmer.disableallsensors;                                             % disable all sensors
                % TODO: Added in mod
                %     shimmer.setenabledsensors(PPGChannel,1);                               % enable PPG Channel
%                 shimmer.setenabledsensors(SensorMacros.ACCEL,1,SensorMacros.MAG,1,...  % Enable the shimmer accelerometer, magnetometer, gyroscope and battery voltage monitor
%                     SensorMacros.GYRO,1, SensorMacros.INTA13, 1,SensorMacros.INTA14, 1,SensorMacros.GSR, 1);
                if app.AccelerometerCheckBox.Value == true
                    shimmer.setenabledsensors(SensorMacros.ACCEL,1,SensorMacros.MAG,1,...  % Enable the shimmer accelerometer, magnetometer, gyroscope and battery voltage monitor
                        SensorMacros.GYRO,1);
                end
                if app.PPGCheckBox.Value == true
                    shimmer.setenabledsensors(SensorMacros.INTA13, 1,SensorMacros.INTA14, 1);
                end
                if app.GSRCheckBox.Value == true
                    shimmer.setenabledsensors(SensorMacros.GSR, 1);
                    shimmer.setaccelrange(0);
                end
                shimmer.setinternalexppower(1);                                        % set internal expansion power
                if (shimmer.start)                                                     % TRUE if the shimmer starts streaming
                    
                    plotData = [];
                    timeStamp = [];
                    filteredplotData = [];
                    heartRate = [];
                    storeData = [];
                   
                    startTime = now();
                    elapsedTime = 0;                                                   % reset to 0
                    tic;                                                               % start timer
                    
                    while (elapsedTime < captureDuration) && (app.StopState == false)   
                   
                        pause(DELAY_PERIOD);                                           % pause for this period of time on each iteration to allow data to arrive in the buffer
                        
                        %             [newData,signalNameArray,~,~] = shimmer.getdata('c');   % Read the latest data from shimmer data buffer, signalFormatArray defines the format of the data and signalUnitArray the unit
                        [newData,signalNameArray,signalFormatArray,signalUnitArray] = shimmer.getdata('c');   % Read the latest data from shimmer data buffer, signalFormatArray defines the format of the data and signalUnitArray the unit
                        
                        if (firsttime==true && isempty(newData)~=1) && (isRecorded == true)
                            %                 firsttime = writeHeadersToFile(fileName,signalNameArray,signalFormatArray,signalUnitArray);
%                             firsttime = writeHeadersToFile(dataFile,...
%                                 [signalNameArray {char('Heart Rate'), char('PPG Filtered')}],...
%                                 [signalFormatArray {char('cal'), char('tab')}],...
%                                 [signalUnitArray {char('mV'), char('BPM')}]);
                            if app.PPGCheckBox.Value == true
                                signalNameArray = [signalNameArray {char('Heart Rate'), char('PPG Filtered')}];
                                signalFormatArray= [signalFormatArray {char('cal'), char('tab')}];
                                signalUnitArray = [signalUnitArray {char('mV'), char('BPM')}];
                            end
                             firsttime = writeHeadersToFile(dataFile,...
                                signalNameArray,...
                                signalFormatArray,...
                                signalUnitArray);
                        end

                        if ~isempty(newData)                                                          % TRUE if new data has arrived
                            % get signal indices
                            chIndex(1) = find(ismember(signalNameArray, 'Time Stamp'));
                            chIndex(2) = find(ismember(signalNameArray, ['Internal ADC A' num2str(PPGChannelNum)]));   % PPG data output
                            PPGData = newData(:,chIndex(2));
                            PPGDataFiltered = PPGData;
                            PPGDataFiltered = lpfPPG.filterData(PPGDataFiltered);                       % filter with low pass filter
                            newheartRate = PPG2HR.ppgToHrConversion(PPGDataFiltered, newData(:,chIndex(1)));                   % compute Heart Rate from PPG data
                            
                            plotData = [plotData; PPGData];                                             % update the plotDataBuffer with the new PPG data
                            filteredplotData = [filteredplotData; PPGDataFiltered];                     % update the filteredplotData buffer with the new filtered PPG data
                            heartRate = [heartRate; newheartRate];                                      % update the filteredHRData buffer with the new filtered Heart Rate data
                            numPlotSamples = size(plotData,1);
                            numSamples = numSamples + size(newData,1);
                            % catch time stamp and modify
                            newData(:,chIndex(1)) = seconds(startTime + milliseconds(newData(:,chIndex(1))));
                            % To decode timestamp, you must convert to seconds and then datestr 
                            timeStampNew = newData(:,chIndex(1));                                       % get timestamps
                            timeStamp = [timeStamp; timeStampNew];
                            
                            newstoreData = [timeStampNew PPGData PPGDataFiltered newheartRate];
                            storeData = [storeData; newstoreData];
                            
                            if isRecorded == true
                            %                 dlmwrite(fileName, storeData, '-append', 'delimiter', '\t','precision',16);                % append the new data to the file in a tab delimited format
                            dlmwrite(dataFile, newData, '-append', 'delimiter', '\t','precision',16); % Append the new data to the file in a tab delimited format
                            end
                            
                            if numSamples > NO_SAMPLES_IN_PLOT
                                plotData = plotData(numPlotSamples-NO_SAMPLES_IN_PLOT+1:end,:);
                                filteredplotData = filteredplotData(numPlotSamples-NO_SAMPLES_IN_PLOT+1:end,:);
                                heartRate = heartRate(numPlotSamples-NO_SAMPLES_IN_PLOT+1:end,:);
                            end
                            sampleNumber = max(numSamples-NO_SAMPLES_IN_PLOT+1,1):numSamples;
                            timeVector = sampleNumber .* (1/fs);
                            if app.PlotCheckBox.Value == true
                                if (string(app.PlotsTabGroup.SelectedTab.Title) == "Heart Rate")
                                    plot(app.PPGAxes, timeVector, filteredplotData(:,1));                 % plot the filtered PPG data
                                    legend(app.PPGAxes,'Filtered PPG (mV)');
                                    xlim(app.PPGAxes,[timeVector(1) timeVector(end)]);
                                    ylim(app.PPGAxes,'auto');
                                    plot(app.HRAxes, timeVector, heartRate);                             % plot the Heart Rate data
                                    legend(app.HRAxes,'Heart Rate (BPM');
                                    xlim(app.HRAxes,[timeVector(1) timeVector(end)]);
                                    ylim(app.HRAxes,'auto');
                                end
                            end
                            
                        end
                        
                        elapsedTime = elapsedTime + toc;                               % stop timer and add to elapsed time
                        tic;                                                           % start timer
                        
                    end
                    
                    elapsedTime = elapsedTime + toc;                                   % stop timer
                    fprintf('The percentage of received packets: %d \n',shimmer.getpercentageofpacketsreceived(timeStamp)); % Detect loss packets
                    shimmer.stop;                                                      % stop data streaming
                    app.RecordingLamp.Color = [0.00,1.00,0.00];
                end
                shimmer.disconnect;
                app.StartTime = startTime;
                app.isRunning = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {490, 490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {348, '1x', 226};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopState = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.LiveTab = uitab(app.TabGroup);
            app.LiveTab.Title = 'Live';
            app.RecordingLampLabel = uilabel(app.LiveTab);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.LiveTab);
            app.GoButton = uibutton(app.LiveTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.PPGNumEditFieldLabel = uilabel(app.LiveTab);
            app.PPGNumEditFieldLabel.Text = 'PPG Num';
            app.PPGNumEditField = uieditfield(app.LiveTab, 'numeric');
            app.PPGNumEditField.Value = 13;
            app.TimeEditFieldLabel = uilabel(app.LiveTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.LiveTab, 'numeric');
            app.TimeEditField.Value = 60;
            app.PathEditFieldLabel = uilabel(app.LiveTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.LiveTab, 'text');
            app.PathEditField.Value = 'C:\Users\sedur\Documents\gits\Shimmer';
            app.PlotCheckBox = uicheckbox(app.LiveTab);
            app.PlotCheckBox.Text = 'Plot';
            app.PlotCheckBox.Value = true;
            app.SelectButton = uibutton(app.LiveTab, 'push');
            app.SelectButton.Text = 'Select';
            app.COMPortEditFieldLabel = uilabel(app.LiveTab);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.LiveTab, 'text');
            app.COMPortEditField.Value = '6';
            app.StopButton = uibutton(app.LiveTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FileEditFieldLabel = uilabel(app.LiveTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.LiveTab, 'text');
            app.FileEditField.Editable = 'off';
            app.ContinuousCheckBox = uicheckbox(app.LiveTab);
            app.ContinuousCheckBox.Text = 'Continuous';
            app.WriteToFileCheckBox = uicheckbox(app.LiveTab);
            app.WriteToFileCheckBox.Text = 'Write To File';
            app.SensorsPanel = uipanel(app.LiveTab);
            app.SensorsPanel.Title = 'Sensors';
            app.PPGCheckBox = uicheckbox(app.SensorsPanel);
            app.PPGCheckBox.Text = 'PPG';
            app.PPGCheckBox.Value = true;
            app.AccelerometerCheckBox = uicheckbox(app.SensorsPanel);
            app.AccelerometerCheckBox.Text = 'Accelerometer';
            app.GSRCheckBox = uicheckbox(app.SensorsPanel);
            app.GSRCheckBox.Text = 'GSR';
            app.GSRCheckBox.Value = true;
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.FileEditField_2Label = uilabel(app.LoadTab);
            app.FileEditField_2Label.Text = 'File';
            app.FileEditField_2 = uieditfield(app.LoadTab, 'text');
            app.SelectButton_2 = uibutton(app.LoadTab, 'push');
            app.SelectButton_2.Text = 'Select';
            app.ProcessButton = uibutton(app.LoadTab, 'push');
            app.ProcessButton.Text = 'Process';
            app.CenterPanel = uipanel(app.GridLayout);
            app.PlotsTabGroup = uitabgroup(app.CenterPanel);
            app.HeartRateTab = uitab(app.PlotsTabGroup);
            app.HeartRateTab.Title = 'Heart Rate';
            app.HRAxes = uiaxes(app.HeartRateTab);
            title(app.HRAxes, 'Hear Rate')
            xlabel(app.HRAxes, 'Time')
            ylabel(app.HRAxes, 'BPM')
            app.PPGAxes = uiaxes(app.HeartRateTab);
            title(app.PPGAxes, 'PPG')
            xlabel(app.PPGAxes, 'Time')
            ylabel(app.PPGAxes, 'mV')
            app.GSRTab = uitab(app.PlotsTabGroup);
            app.GSRTab.Title = 'GSR';
            app.AccelerationTab = uitab(app.PlotsTabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.ReviewTab = uitab(app.TabGroup2);
            app.ReviewTab.Title = 'Review';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShimmerPPG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end